classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptionsMenu                     matlab.ui.container.Menu
        DataImportMenu                  matlab.ui.container.Menu
        T1T2RatioConstraintMenu         matlab.ui.container.Menu
        MiscelleanousParametersMenu     matlab.ui.container.Menu
        UIAxes                          matlab.ui.control.UIAxes
        DoFitButton                     matlab.ui.control.Button
        XpDataSwitchLabel               matlab.ui.control.Label
        XpDataSwitch                    matlab.ui.control.Switch
        SefheaderXpdataSwitchLabel      matlab.ui.control.Label
        SefheaderXpdataSwitch           matlab.ui.control.Switch
        SelectTauBrangepsPanel          matlab.ui.container.Panel
        SelectTauDrange10xminTauL10xmaxTauLPanel  matlab.ui.container.Panel
        SelectTauLrangepsPanel          matlab.ui.container.Panel
        TauLminLabel                    matlab.ui.control.Label
        TauLmaxLabel                    matlab.ui.control.Label
        RelaxationRateOffsetSwitchLabel  matlab.ui.control.Label
        RelaxationRateOffsetSwitch      matlab.ui.control.Switch
        OffsetvalueEditField            matlab.ui.control.NumericEditField
        InteractionsTypeButtonGroup     matlab.ui.container.ButtonGroup
        HHButton                        matlab.ui.control.RadioButton
        HMButton                        matlab.ui.control.RadioButton
        HHHMButton                      matlab.ui.control.RadioButton
        ParamagDensityNparaPanel        matlab.ui.container.Panel
        NovaluestestedEditFieldLabel    matlab.ui.control.Label
        NovaluestestedEditField         matlab.ui.control.NumericEditField
        StartingValueEditFieldLabel     matlab.ui.control.Label
        StartingValueEditField          matlab.ui.control.NumericEditField
        IncrementationEditFieldLabel    matlab.ui.control.Label
        IncrementationEditField         matlab.ui.control.NumericEditField
        SurfaceVolumeRatioPanel         matlab.ui.container.Panel
        NovaluestestedEditField_2Label  matlab.ui.control.Label
        NovaluestestedEditField_2       matlab.ui.control.NumericEditField
        StartingValueEditField_2Label   matlab.ui.control.Label
        StartingValueEditField_2        matlab.ui.control.NumericEditField
        IncrementationEditField_2Label  matlab.ui.control.Label
        IncrementationEditField_2       matlab.ui.control.NumericEditField
        IntraMolecularContributionSwitchLabel  matlab.ui.control.Label
        IntraMolecularContributionSwitch  matlab.ui.control.Switch
        IntraMolContribution            matlab.ui.control.NumericEditField
        LeastSquareMethodButtonGroup    matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.RadioButton
        LogarithmicButton               matlab.ui.control.RadioButton
        SefFilesOnlyButton              matlab.ui.control.RadioButton
        DiscardXpFrequenciesPanel       matlab.ui.container.Panel
        FreqStartEditFieldLabel         matlab.ui.control.Label
        FreqStartEditField              matlab.ui.control.NumericEditField
        FreqEndEditFieldLabel           matlab.ui.control.Label
        FreqEndEditField                matlab.ui.control.NumericEditField
        TauBminLabel                    matlab.ui.control.Label
        TauBmaxLabel                    matlab.ui.control.Label
        ImposedValuesPanel              matlab.ui.container.Panel
        TauBpsEditFieldLabel            matlab.ui.control.Label
        TauBpsEditField                 matlab.ui.control.NumericEditField
        TauLpsEditFieldLabel            matlab.ui.control.Label
        TauLpsEditField                 matlab.ui.control.NumericEditField
        TauDpsEditFieldLabel            matlab.ui.control.Label
        TauDpsEditField                 matlab.ui.control.NumericEditField
        ParaDensityspinsnm3EditFieldLabel  matlab.ui.control.Label
        ParaDensityspinsnm3EditField    matlab.ui.control.NumericEditField
        SurfaceVolumeRatioxEditFieldLabel  matlab.ui.control.Label
        SurfaceVolumeRatioxEditField    matlab.ui.control.NumericEditField
        SurfDensityspinsnm3EditFieldLabel  matlab.ui.control.Label
        SurfDensityspinsnm3EditField    matlab.ui.control.NumericEditField
        PrintResultsPanel               matlab.ui.container.Panel
        TauBLabel                       matlab.ui.control.Label
        TauDLabel                       matlab.ui.control.Label
        TauLLabel                       matlab.ui.control.Label
        hLabel                          matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        NparaLabel                      matlab.ui.control.Label
        T1T2Label                       matlab.ui.control.Label
        Chi2minLabel                    matlab.ui.control.Label
        TauDTauLLabel                   matlab.ui.control.Label
        Message                         matlab.ui.control.Label
        SaveOutputsafterFittingLabel    matlab.ui.control.Label
        SaveOutputsafterFittingSwitch   matlab.ui.control.Switch
        LogLogScaleSwitchLabel          matlab.ui.control.Label
        LogLogScaleSwitch               matlab.ui.control.Switch
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            axB;    % for the axes tauB
            lB;     % for the line tauB
            axD;    % for the axes tauD
            lD;     % for the line tauD
            axL;    % for the axes tauL
            lL;     % for the line tauL
    end
    
    methods (Access = private)

    % Function that responds to the movement of the sliders tauB
    function lMovingtauB(~, source)
    global B_ind Bind
        % Always keep the position rounded to 1 decimal place
        source.Position(1,1) = round(source.Position(1,1),1)
        source.Position(2,1) = round(source.Position(2,1),1)
        % Add any other action here triggered by the movement of the sliders
     disp(['ROI moving previous position: ' mat2str(source.Position)])
     B_ind=mat2str(source.Position)
%      %Display values tauB
%             strB = convertCharsToStrings(B_ind);
%             TestB = cellfun(@str2num,strB,'UniformOutput',false);
%             B=cell2mat(TestB);
%             B_min=min(B(B > 1));
%             B_min=round(B_min);
%             B_max=max(B(B > 1));
%             B_max=round(B_max);
%             Bind=[B_min,B_max];
%             FOK=Bind(1,1)
%             app.TauBmaxLabel.Text=string(Bind(1,2))
%             app.TauBminLabel.Text=string(Bind(1,1))
%             fun = @(~,e)set(app.TauBmaxLabel,'String',num2strg(get(e.AffectedObject,'Value')))
%             app.TauBpsEditField.Value = Bind(1,2)
    end
     % Function that responds to the movement of the sliders tauL
     function lMovingtauL(~, source)
     global L_ind
        % Always keep the position rounded to 1 decimal place
        source.Position(1,1) = round(source.Position(1,1),1)
        source.Position(2,1) = round(source.Position(2,1),1)
        % Add any other action here triggered by the movement of the sliders
     disp(['ROI moving previous position: ' mat2str(source.Position)])
     L_ind=mat2str(source.Position)
     end
     % Function that responds to the movement of the sliders tauD
     function lMovingtauD(~, source)
     global D_ind
        % Always keep the position rounded to 1 decimal place
        source.Position(1,1) = round(source.Position(1,1),1)
        source.Position(2,1) = round(source.Position(2,1),1)
        % Add any other action here triggered by the movement of the sliders
     disp(['ROI moving previous position: ' mat2str(source.Position)])
     D_ind=mat2str(source.Position)
        %Set Imposed Values
         app.TauLpsEditField.Value=0
         app.TauDpsEditField.Value=0
         app.TauBpsEditField.Value=0
     end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
%         set(app.UIAxes2,'XColor', app.UIAxes2.Parent.Color, 'YColor', app.UIAxes2.Parent.Color);    
        set(app.UIAxes2,'XColor', 'white', 'YColor', 'white');
        
        app.UIAxes2.YAxis.TickLabelInterpreter = 'latex'
        app.UIAxes2.YAxis.TickLabelFormat      = '\\textbf{%g}'

        
%        set(app.UIAxes2.YTickLabel, 'Color', 'black')
                
        which -all images.roi.Line
            %Range selection TauB
            % Create the 1D axes
            app.axB = axes( ...
            app.SelectTauBrangepsPanel,'Color','none','YColor','none','XLim',[4,100],'YTick',[], ...
            'XTick',0:10:100,'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
            'on','Units','pixels','Position',[8,30,294,0] ...
            );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axB);
            app.axB.Toolbar.Visible = 'off';
            % Add the line ROI
            app.lB = images.roi.Line(app.axB,'Position',[4,0;100,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.lB,'MovingROI',@(varargin)lMovingtauB(app,app.lB));

        %Range selection TauD
            % Create the 1D axes
            app.axD = axes( ...
            app.SelectTauDrange10xminTauL10xmaxTauLPanel,'Color','none','YColor','none','XLim',[-2,2],'YTick',[], ...
            'XTick',[-2,-1,0,1,2],'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
            'on','Units','pixels','Position',[8,30,294,0] ...
            );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axD);
            app.axD.Toolbar.Visible = 'off';
            % Add the line ROI
            app.lD = images.roi.Line(app.axD,'Position',[-1,0;1,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.lD,'MovingROI',@(varargin)lMovingtauD(app,app.lD));
        %Range selection TauL
            % Create the 1D axes
            app.axL = axes( ...
            app.SelectTauLrangepsPanel,'Color','none','YColor','none','XScale','log','XLim',[10000,1e8],'YTick',[], ...
            'XTick',[1e4,1e5,1e6,1e7,1e8],'TickDir','both','TickLength',[0.03,0.035],'XMinorTick', ...
            'on','Units','pixels','Position',[8,30,294,0] ...
            );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axL);
            app.axL.Toolbar.Visible = 'off';
            % Add the line ROI
            app.lL = images.roi.Line(app.axL,'Position',[10000,0;100000,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.lL,'MovingROI',@(varargin)lMovingtauL(app,app.lL))
        % Input default parameters
            app.NovaluestestedEditField.Value=50;
            app.StartingValueEditField.Value=0.0006;
            app.IncrementationEditField.Value=0.000001;
            app.NovaluestestedEditField_2.Value=50;
            app.StartingValueEditField_2.Value=0.0008;
            app.IncrementationEditField_2.Value=0.000001;
            app.SurfDensityspinsnm3EditField.Value=66.6
             %Set Imposed Values
%      set(app.TauLpsEditField.Value,'String','')
        end

        % Button pushed function: DoFitButton
        function DoFitButtonPushed(app, event)
            global R1Xp FreqXp HHTau HMTau IntPts B_ind L_ind D_ind...
                ValXpFreq ValHeaderSdf Spin R1_Offset T1T2_freq R1bb_Intra NbWdens...
                TauB_imp TauD_imp TauL_imp Nv_imp x_imp chi_style No_freq_start...
                No_freq_end Resid R1b_HM_best R1L_HM_best R1_constantOffset_best ...
                R1bb_HH_Intra_best...
                R1bb_HH_best R1LL_HH_best R1bL_HH_best R1Lb_HH_best ...
                R1b_HH_best R1L_HH_best tD tL tB Ns N_opt x_opt h xN_count_min_chi2 T1T2_best...
                HH_triggerOn HM_triggerOn R1Xp_in Save_Switch T1T2Ratio LoglogScale ...
                Offset_Switch Offset_Intra_Switch
        
            set(app.UIAxes2,'XColor', 'white', 'YColor', 'white');
                      
            cla(app.UIAxes2)
            cla(app.UIAxes)
                    
             if strcmp(LoglogScale,'On') == 1
             loglog(app.UIAxes,FreqXp,R1Xp_in, 'o')   
             else 
             set(app.UIAxes, 'XScale', 'log' , 'YScale', 'linear' )
             semilogx(app.UIAxes,FreqXp,R1Xp_in,'o')
             end
             legend(app.UIAxes,'R1 expt','southwest');
                
            % Set Input Parameters
            Npara = app.NovaluestestedEditField.Value
            Npara_start = app.StartingValueEditField.Value
            dNpara = app.IncrementationEditField.Value
            
            Nx = app.NovaluestestedEditField_2.Value
            x_start =  app.StartingValueEditField_2.Value
            dx = app.IncrementationEditField_2.Value
            
            Ns = app.SurfDensityspinsnm3EditField.Value
                          
            % Set Interaction Type
            
            if app.HHButton.Value == true
            HH_triggerOn = 1
            HM_triggerOn = 0

            elseif app.HMButton.Value == true
            HH_triggerOn = 0
            HM_triggerOn = 1

            elseif app.HHHMButton.Value == true
            HM_triggerOn = 1
            HH_triggerOn = 1

            end
            
            % Set least square methods
            
            if app.LinearButton.Value == true
            chi_style={'lin'}
            elseif app.LogarithmicButton.Value == true
            chi_style={'log'}
            elseif app.SefFilesOnlyButton.Value == true
            chi_style={'wtlin'}
            end
            
            % Set Frequency range for fitting
            No_freq_start = app.FreqStartEditField.Value
            No_freq_end = app.FreqEndEditField.Value
            
            %Set trigger offset
            if strcmp(Offset_Intra_Switch,'On')==1
            R1bb_Intra = app.IntraMolContribution.Value
            else
            R1bb_Intra = 0
            end
            
            if strcmp(Offset_Switch,'On')==1
            R1_Offset = app.OffsetvalueEditField.Value
            else 
            R1_Offset = 0
            end
               
            HM_triggerOn
            %Set LoglogScale Display
            LoglogScale = app.LogLogScaleSwitch.Value
            
            ValXpFreq = app.XpDataSwitch.Value
            display(ValXpFreq)
            ValHeaderSdf = app.SefheaderXpdataSwitch.Value
            display(ValHeaderSdf)
            display(ValXpFreq)
            
            app.Message.Text = 'Fitting Start'
            pause(2)
            app.Message.Text = ''
            
            try
                
           [Freq,R1Th,R1_HM_best,R1_HH_best,tL,tB,tD,h,x_opt,...
            N_opt,T1T2_best,Resid,R1b_HM_best, R1L_HM_best, R1_constantOffset_best, ...
            R1bb_HH_Intra_best, ...
            R1bb_HH_best,R1LL_HH_best,R1bL_HH_best,R1Lb_HH_best,...
            R1b_HH_best,R1L_HH_best, xN_count_min_chi2,R1Xp] = Fit_3TM(R1Xp_in,...
            FreqXp,ValXpFreq,ValHeaderSdf,...
            HHTau,HMTau,IntPts,B_ind,L_ind,D_ind,Spin,R1_Offset,...
            Npara,Npara_start,dNpara,Nx,x_start,dx,HH_triggerOn,...
            HM_triggerOn,T1T2_freq,R1bb_Intra,chi_style,NbWdens,...
            No_freq_start,No_freq_end,TauB_imp,TauD_imp,TauL_imp,Nv_imp,x_imp,Ns,T1T2Ratio)
        
            catch ME
                waitfor( errordlg( getReport(ME)))
                clear R1Xp_in FreqXp ValXpFreq ValHeaderSdf...
            HHTau HMTau IntPts B_ind L_ind D_ind Spin R1_Offset ...
            Npara Npara_start dNpara Nx x_start dx  ...
             T1T2_freq R1bb_Intra chi_style NbWdens ...
            No_freq_start No_freq_end TauB_imp TauD_imp ... 
            TauL_imp Nv_imp x_imp Ns T1T2Ratio Resid
        
            end
                   
            app.Message.Text = 'Fitting Done'
            pause(2)
            app.Message.Text = ''
            
            display(LoglogScale)
            
             %Plot Results
             if HH_triggerOn == 1         
                 if strcmp(LoglogScale,'On') == 1
                 loglog(app.UIAxes,FreqXp,R1Xp,'o',Freq, R1Th,'k')   
                 else 
                 set(app.UIAxes, 'XScale', 'log' , 'YScale', 'linear' )
                 semilogx(app.UIAxes,FreqXp,R1Xp,'o',Freq, R1Th,'k')
                 end
             legend(app.UIAxes,'R1 expt','R1 3TM fit','Location','southwest');
             end
             if HM_triggerOn*HH_triggerOn == 1
                 if strcmp(LoglogScale,'On') == 1
                 loglog(app.UIAxes,FreqXp, R1Xp,'o',Freq, R1Th,'k', Freq, R1_HM_best,'g',Freq, R1_HH_best,'r')  
                 else
                 set(app.UIAxes, 'XScale', 'log' , 'YScale', 'linear' )
                 semilogx(app.UIAxes,FreqXp, R1Xp,'o',Freq, R1Th,'k', Freq, R1_HM_best,'g',Freq, R1_HH_best,'r')
                 end
             legend(app.UIAxes,'R1 expt','R1 3TM fit','R1 HM','R1 HH','Location','east');   
             elseif HM_triggerOn == 1
                  if strcmp(LoglogScale,'On') == 1
                  loglog(app.UIAxes,FreqXp,R1Xp,'o',Freq, R1Th,'k')
                  else
                  set(app.UIAxes, 'XScale', 'log' , 'YScale', 'linear' )
                  semilogx(app.UIAxes,FreqXp,R1Xp,'o',Freq, R1Th,'k')
                  end
             legend(app.UIAxes,'R1 expt','R1 3TM fit','Location','southwest');
             end
             
            if HH_triggerOn == 1 && HM_triggerOn*HH_triggerOn == 0
                N_opt=0.00
            end
                      
            app.Message.Text = 'Fitted Curves Plotted'
            pause(2)
            app.Message.Text = ''
            
            set(app.UIAxes2,'XColor', 'white', 'YColor', 'black');
            app.UIAxes2.YTick = ([0]);
            plot(app.UIAxes2, Resid, 'r o')
            
           
            xline(app.UIAxes2, 0)
            yline(app.UIAxes2, 0)
            
            app.Message.Text = 'Error Plotted'
            pause(2)
            app.Message.Text = ''
             
             %Print Results
             app.TauBLabel.Text = sprintf('TauB: %.2d ps',tB);
             app.TauDLabel.Text = sprintf('TauD: %.2d us',tD);
             app.TauLLabel.Text = sprintf('TauL: %.2d us',tL);
             app.hLabel.Text = sprintf('h: %.2d um',h);
             app.xLabel.Text = sprintf('x: %.2d',x_opt);
             app.NparaLabel.Text = sprintf('Npara: %.2d spins/nm^3',N_opt);
             app.T1T2Label.Text = sprintf('T1/T2: %.2d',T1T2_best);
             app.Chi2minLabel.Text = sprintf('Chi2 min: %.4d',xN_count_min_chi2);
             app.TauDTauLLabel.Text = sprintf('TauD/TauL: %.2d', tD/tL)
             
            app.Message.Text = 'Output Printed'
            pause(2)
            app.Message.Text = ''
            
            
            %Reset Imposed Values
            clear TauB_imp TauD_imp TauL_imp Nv_imp x_imp;
            
            %Save Output
            
            if strcmp(Save_Switch,'On') == 1
            prompt={'Enter the name file for output'};
            dlgtitle='Saved Fitting Output'
            definput={''}
            opts.Interpreter='tex';
            Input=inputdlg(prompt,dlgtitle,[1,50],definput,opts);
            filename=(Input{1})

            
            formatSpec='%s.dat';
            baseFileName = sprintf(formatSpec, filename)
            fullFileName = fullfile(pwd, baseFileName);
            fid= fopen(fullFileName ,'wt+');
       
 
            fprintf(fid,'3TM_Fit_Interface_1.0\nPlease reference the following three articles in any outputs that use this software:\n\nThe 3-tau model\n--------------\nFaux D A,  Howlett N C and McDonald P J\nNuclear magnetic resonance relaxation due to the translational diffusion of fluid confined to quasi-two-dimensional pores\nPhys. Rev. E., 95, 033116 (2017)\nDOI: 10.1103/PhysRevE.95.033116\nFaux D A and McDonald P J\nExplicit calculation of nuclear magnetic resonance relaxation rates in small pores to elucidate molecular scale fluid dynamics\nPhys. Rev. E., 95, 033117 (2017)\nDOI: 10.1103/PhysRevE.95.033117\n');
            fprintf(fid,'Introducing the software\n------------------------\nFaux D A, Kogon R, Bortolotti V and McDonald P J\nAdvances in the interpretation of frequency-dependent nuclear-magnetic resonance measurements from porous material\nMolecules, 24 (20) 3688 (2019)\nhttps://doi.org/10.3390/molecules24203688');

            
            expt_data_set=''
            formatSpec = ' %13s  \n';
            fprintf(fid,formatSpec,expt_data_set);
            
            fprintf(fid,'     \n');
            
            if strcmp(chi_style,'lin')==1
                fitmethod = 'Linear least squares fit';
            elseif strcmp(chi_style,'log')==1
                fitmethod = 'Logarithmic least squares fit';
            else
                fitmethod = 'Weighted linear least squares fit';
            end
                                              
            fprintf(fid,'     \n');
            fprintf(fid,formatSpec,fitmethod); fprintf(fid,'     \n');
            fprintf(fid,'     \n');
            fprintf(fid,'%16s,%16d,%16s,%16d \n','     Fit start',No_freq_start,'     Fit end',(No_freq_end));
            fprintf(fid,'     \n');
            fprintf(fid,'%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s,%16s \n', ...
            '       frequency','       R1 (expt)','        R1 (3TM)','        residual', ...
            '         R1-b-HM','         R1-L-HM','     R1-total-HM','       R1-offset', ...
            '        R1-intra', ...
            '       R1W-bb-HH','       R1W-LL-HH','       R1W-bL-HH','       R1W-Lb-HH', ...
            '        R1W-b-HH','        R1W-L-HH','    R1W-total-HH');
            
            fprintf(fid,'%16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f %16.4f \n', ...
            [Freq R1Xp R1Th Resid R1b_HM_best R1L_HM_best R1_HM_best R1_constantOffset_best R1bb_HH_Intra_best R1bb_HH_best R1LL_HH_best R1bL_HH_best R1Lb_HH_best R1b_HH_best R1L_HH_best R1_HH_best]');
            fprintf(fid,'     \n'); fprintf(fid,'     \n');
            
            fprintf(fid, '%16s \n','         BEST FIT');
            fprintf(fid, '%16s,%16.6f,%16s \n','         tau_L =',tL,' us              ');
            fprintf(fid, '%16s,%16.6f,%16s \n','         tau_d =',tD,' us              ');
            fprintf(fid, '%16s,%16.3f,%16s \n','         tau_b =',tB,' ps              ');
            fprintf(fid,'     \n');
            fprintf(fid, '%16s,%16.3f \n',     '   tau_d/tau_L =',tD/tL);
            fprintf(fid,'     \n');
            
            if HH_triggerOn==1
            fprintf(fid, '%16s,%16.6f,%16s \n','           Ns  =',Ns,' surf spins/nm^3');
            fprintf(fid, '%16s,%16.6f,%16s \n','           Nb  =',NbWdens,' bulk spins/nm^3');
            end
            
            if HM_triggerOn==1
            fprintf(fid, '%16s,%16.7f,%16s \n','            N  =',N_opt,' e-spins/nm^3   ');
            end
            
            fprintf(fid, '%16s,%16.6f,%16s \n','            x  =',x_opt,' surf/vol ratio ');
            fprintf(fid, '%16s,%16.3f,%16s \n','            h  =',h,    ' microns        ');
            
            if isempty(R1_Offset)==0
            fprintf(fid, '%16s,%16.3f,%16s \n','       offset  =',R1_Offset,'s^-1        ');
            end
            
            fprintf(fid,'     \n'); 
            fprintf(fid, '%16s,%16.5f \n',     '     min chi2  =',xN_count_min_chi2);
%             fprintf(fid,'     \n'); 
%             fprintf(fid, '%16s,%16d \n',       '   tau_L index =',tL_best_index+iL_start-1);
%             fprintf(fid, '%16s,%16d \n',       '   tau_d index =',td_best_index-(Nd_rd-1)/2);
%             fprintf(fid, '%16s,%16d \n',       '   tau_b index =',tb_best_index+ib_start-1);
            fprintf(fid,'     \n'); 
            fprintf(fid, '%16s,%16.2f,%16s \n','   T1/T2 ratio =',T1T2_best,'                 ');
            fprintf(fid, '%16s,%16.2f,%16s \n',' at T1/T2 freq =',T1T2_freq,' MHz             ');
            
            fclose(fid);true;
                     
            app.Message.Text = 'Output Saved'
            pause(2)
            app.Message.Text = ''
            
            else 
            app.Message.Text = 'Thanks for using 3TM Interface'
            pause(2)
            app.Message.Text = ''
            end
            app.Message.Text = 'Thanks for using 3TM Interface'
            pause(2)
            app.Message.Text = ''
           
        end

        % Value changed function: RelaxationRateOffsetSwitch
        function RelaxationRateOffsetSwitchValueChanged(app, event)
            global R1_Offset Offset_Switch
            Offset_Switch = app.RelaxationRateOffsetSwitch.Value;
            R1_Offset = app.OffsetvalueEditField.Value;
            if Offset_Switch=="Off"
                R1_Offset=0
            app.Message.Text = 'Offset off'
            pause(2)
            app.Message.Text = ''
            else R1_Offset=R1_Offset
            app.Message.Text = 'Offset on'
            pause(2)
            app.Message.Text = ''
            end

        end

        % Value changed function: IntraMolecularContributionSwitch
        function IntraMolecularContributionSwitchValueChanged(app, event)
            global R1bb_Intra Offset_Intra_Switch
            Offset_Intra_Switch = app.IntraMolecularContributionSwitch.Value;
            if Offset_Intra_Switch=="Off"
                R1bb_Intra=0
            app.Message.Text = 'Intra molecular contribution off'
            pause(2)
            app.Message.Text = ''
            else R1bb_Intra=R1bb_Intra
            app.Message.Text = 'Intra molecular contribution on'
            pause(2)
            app.Message.Text = ''
            end
        end

        % Value changed function: TauBpsEditField
        function TauBpsEditFieldValueChanged(app, event)
            global TauB_imp
            TauB_imp = app.TauBpsEditField.Value;
        end

        % Value changed function: TauLpsEditField
        function TauLpsEditFieldValueChanged(app, event)
            global TauL_imp
            TauL_imp = app.TauLpsEditField.Value;
        end

        % Value changed function: TauDpsEditField
        function TauDpsEditFieldValueChanged(app, event)
            global TauD_imp
            TauD_imp = app.TauDpsEditField.Value;
        end

        % Value changed function: ParaDensityspinsnm3EditField
        function ParaDensityspinsnm3EditFieldValueChanged(app, event)
            global Nv_imp
            Nv_imp = app.ParaDensityspinsnm3EditField.Value;          
        end

        % Value changed function: SurfaceVolumeRatioxEditField
        function SurfaceVolumeRatioxEditFieldValueChanged(app, event)
            global x_imp
            x_imp = app.SurfaceVolumeRatioxEditField.Value;          
        end

        % Selection changed function: InteractionsTypeButtonGroup
        function InteractionsTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InteractionsTypeButtonGroup.SelectedObject;
            if app.HHButton.Value == true

            app.Message.Text = 'HH interaction selected'
            pause(2)
            app.Message.Text = ''
            
            elseif app.HMButton.Value == true

            app.Message.Text = 'HM interaction selected'
            pause(2)
            app.Message.Text = ''
            
            elseif app.HHHMButton.Value == true

            app.Message.Text = 'Both interactions selected'
            pause(2)
            app.Message.Text = ''
            
            end
        end

        % Selection changed function: LeastSquareMethodButtonGroup
        function LeastSquareMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.LeastSquareMethodButtonGroup.SelectedObject;
            if app.LinearButton.Value == true
            app.Message.Text = 'Linear Minimization Selected'
            pause(2)
            app.Message.Text = ''
            elseif app.LogarithmicButton.Value == true
            app.Message.Text = 'Logarithmic Minimization Selected'
            pause(2)
            app.Message.Text = ''
            elseif app.SefFilesOnlyButton.Value == true
            app.Message.Text = 'W.Linear Minimization Selected'
            pause(2)
            app.Message.Text = ''
            end
        end

        % Value changed function: SaveOutputsafterFittingSwitch
        function SaveOutputsafterFittingSwitchValueChanged(app, event)
          global Save_Switch
            Save_Switch = app.SaveOutputsafterFittingSwitch.Value;  
        end

        % Menu selected function: MiscelleanousParametersMenu
        function MiscelleanousParametersMenuSelected(app, event)
        global IntPts Spin T1T2_freq NbWdens
        prompt={'Enter the number of interpolation points',...
            'Enter the spin quantum number for paramagnetic ions',...
            'Enter frequency of T1/T2 ratio (MHz)','Enter bulk water spin density (spins/nm^3)'};
            dlgtitle='Parameters'
            definput={'201','2.5','20','66.6'}
            opts.Interpreter='tex';
            Input=inputdlg(prompt,dlgtitle,[1,50],definput,opts);
            if isempty(Input)
            IntPts=201, Spin=2.5, T1T2_freq=20, NbWdens=66.6;   
            else
            IntPts=str2double(Input{1}),Spin=str2double(Input{2});
            T1T2_freq=str2double(Input{3}), NbWdens==str2double(Input{4});
            app.Message.Text = 'Miscelleanous Input Saved'
            pause(2)
            app.Message.Text = ''
            end
        end

        % Menu selected function: T1T2RatioConstraintMenu
        function T1T2RatioConstraintMenuSelected(app, event)
        global T1T2Ratio
        prompt={'Enter the minimum value of T1/T2 ratio',...
            'Enter the maximum value of T1/T2 ratio'};
            dlgtitle='T1/T2 Ratio'
            definput={'0','100'}
            opts.Interpreter='tex';
            Input=inputdlg(prompt,dlgtitle,[1,50],definput,opts);
            if isempty(Input)
               T1T2Ratio=[0,100]; 
            else
            T1T2min=str2double(Input{1});T1T2max=str2double(Input{2});
            app.Message.Text = 'T1/T2 Ratio range selected'
            pause(2)
            app.Message.Text = '' 
                if isempty(Input{1})==1
                    T1T2min=0
                end
                if isempty(Input{2})==1
                    T1T2max=100
                end
                T1T2Ratio=[T1T2min,T1T2max] 
            end
        end

        % Menu selected function: DataImportMenu
        function DataImportMenuSelected2(app, event)
        global R1Xp_in FreqXp NFreq
        
        ValHeaderSdf=app.SefheaderXpdataSwitch.Value
        set(app.UIAxes2,'XColor', 'white', 'YColor', 'white');
        cla(app.UIAxes2)
        cla(app.UIAxes)

        if ValHeaderSdf=="Off"
        %Import csv data 
        
        [FileName,PathName]=uigetfile('*.csv')
        input_file = [PathName,FileName]
            
            if FileName==0
                app.Message.Text = 'Try again to import data'
                pause(2)
                app.Message.Text = ''
            elseif isempty(input_file)==0          
                [file_encoding, bytes_per_char, BOM_size, bytes2char] = UTF_detection(input_file);
                    if isempty(file_encoding)
                    error('No usable input file');
                    end
                fid = fopen(input_file,'rt');
                fread(fid, [1, BOM_size], '*uint8');   %skip the Byte Order Mark
                thisbuffer = fgets(fid);
                extra = mod(length(thisbuffer), bytes_per_char);
                    if extra ~= 0
                      %in little-endian modes, newline would be found in first byte and the 0's after need to be read
                      thisbuffer = [thisbuffer, fread(fid, [1, bytes_per_char - extra], '*uint8')];
                    end
                thisline = bytes2char(thisbuffer);
                data_cell = textscan(thisline, '%s', 'delimiter', ',');   %will ignore the end of lines
                header_fields = reshape(data_cell{1}, 1, []);
                num_field = length(header_fields);
                thisbuffer = fread(fid, [1 inf], '*uint8');
                extra = mod(length(thisbuffer), bytes_per_char);
                    if extra ~= 0
                      thisbuffer = [thisbuffer, zeros(1, bytes_per_char - extra, 'uint8')];
                    end
                thisline = bytes2char(thisbuffer);
                fmt = repmat('%s', 1, num_field);
                data_cell = textscan(thisline, fmt, 'delimiter', ',');
                data_fields_text = horzcat(data_cell{:});
                data_fields = data_fields_text;
                A = [header_fields;data_fields]
                data = str2double(A)   
                
             %Plot csv data  
    %        data=handles.Fit;
                 FreqXp=data(:,1);
                 R1Xp_in=data(:,2);
                 MaxYLim=max(R1Xp_in);            
                    if max(R1Xp_in)>100  
                                     R1Xp_in=1000*R1Xp_in
                                     MaxYLim=max(R1Xp_in)
                                     R1Xp_in=0.001*R1Xp_in
                                     MaxYLim=roundn(MaxYLim*0.001,2)
                    else
                        MaxYLim=ceil(MaxYLim) 
                    end         
                 ax = app.UIAxes;
                 ax.YLim = [0 MaxYLim];
                 semilogx(app.UIAxes,FreqXp,R1Xp_in,'o')
                 title(app.UIAxes, sprintf('R_1 versus Frequency / Data File : %s', FileName))
                 legend(app.UIAxes,'R1 expt');
                 app.Message.Text = 'PLOT DONE'
                 pause(2)
                 app.Message.Text = ''
            end
            %Import sef data    
        else
                [FileName,PathName]=uigetfile('*.sef');
                
            if FileName==0
                app.Message.Text = 'Try again to import data'
                pause(2)
                app.Message.Text = ''
            else
                fid=fopen(FileName,'r');
            % Read/discard 4 lines
                fgetl(fid);                       
                fgetl(fid);                       
                fgetl(fid);
                fgetl(fid);
    
                tlines=cell(0,1);
                tline = fgets(fid)
                
                while ischar(tline)
                    disp(tline);
                    tlines{end+1,1}=tline
                    tline = fgets(fid);
                end
    
                % separate columns
                Test = cellfun(@(cIn) strsplit(cIn,' ')',tlines,'UniformOutput',false);
                Test = [Test{:}]';
                % delete first column
                Test(:,1) = [];
                % convert to double
                TestOut = cellfun(@str2num,Test(:,1:5));
                % extract the experimental frequencies {1}, R1 {2} and uncertainty {5}
                FreqXp=TestOut(:,1);
                R1Xp_in=TestOut(:,3);
                R1Xp_err_in=TestOut(:,5);
                
                %Plot sef Data
    
                semilogx(app.UIAxes,FreqXp,R1Xp_in,'o')
                errorbar(app.UIAxes,FreqXp,R1Xp_in,R1Xp_err_in,'o')
                title(app.UIAxes, sprintf('R_1 versus Frequency / Data File : %s', FileName))
                legend(app.UIAxes,'R1 expt');
                app.Message.Text = 'PLOT DONE'
                pause(2)
                app.Message.Text = ''
             end
        end
        % Input Num Freq
        app.FreqStartEditField.Value=1
        app.FreqEndEditField.Value=numel(FreqXp)
        NFreq=app.FreqEndEditField.Value
        end

        % Value changed function: LogLogScaleSwitch
        function LogLogScaleSwitchValueChanged(app, event)
        global LoglogScale   
        
            LoglogScale = app.LogLogScaleSwitch.Value;
            
         if LoglogScale=="On"
            app.Message.Text = 'Loglog Scale selected'
            pause(2)
            app.Message.Text = '' 
         elseif LoglogScale=="Off"
             app.Message.Text = 'Semilog Scale selected'
             pause(2)
             app.Message.Text = ''   
         end  

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options Menu';
            app.DataImportMenu = uimenu(app.OptionsMenu);
            app.DataImportMenu.MenuSelectedFcn = createCallbackFcn(app, @DataImportMenuSelected2, true);
            app.DataImportMenu.Text = 'Data Import';
            app.T1T2RatioConstraintMenu = uimenu(app.OptionsMenu);
            app.T1T2RatioConstraintMenu.MenuSelectedFcn = createCallbackFcn(app, @T1T2RatioConstraintMenuSelected, true);
            app.T1T2RatioConstraintMenu.Text = 'T1/T2 Ratio Constraint';
            app.MiscelleanousParametersMenu = uimenu(app.OptionsMenu);
            app.MiscelleanousParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @MiscelleanousParametersMenuSelected, true);
            app.MiscelleanousParametersMenu.Text = 'Miscelleanous Parameters';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'R_1 versus Frequency')
            xlabel(app.UIAxes, 'Larmor Frequency (MHz)')
            ylabel(app.UIAxes, 'Relaxation Rate R_1 (s^{-1})')
            app.DoFitButton = uibutton(app.UIFigure, 'push');
            app.DoFitButton.ButtonPushedFcn = createCallbackFcn(app, @DoFitButtonPushed, true);
            app.DoFitButton.Text = 'Do Fit';
            app.XpDataSwitchLabel = uilabel(app.UIFigure);
            app.XpDataSwitchLabel.Text = 'Xp Data';
            app.XpDataSwitch = uiswitch(app.UIFigure, 'slider');
            app.XpDataSwitch.Items = {'Ascending', 'Descending'};
            app.XpDataSwitch.Value = 'Ascending';
            app.SefheaderXpdataSwitchLabel = uilabel(app.UIFigure);
            app.SefheaderXpdataSwitchLabel.Text = 'Sef header Xp data';
            app.SefheaderXpdataSwitch = uiswitch(app.UIFigure, 'slider');
            app.SelectTauBrangepsPanel = uipanel(app.UIFigure);
            app.SelectTauBrangepsPanel.Title = 'Select TauB range (ps)';
            app.SelectTauDrange10xminTauL10xmaxTauLPanel = uipanel(app.UIFigure);
            app.SelectTauDrange10xminTauL10xmaxTauLPanel.Title = 'Select TauD range: 10^(xmin)*TauL // 10^(xmax)*TauL';
            app.SelectTauLrangepsPanel = uipanel(app.UIFigure);
            app.SelectTauLrangepsPanel.Title = 'Select TauL range (ps)';
            app.TauLminLabel = uilabel(app.SelectTauLrangepsPanel);
            app.TauLminLabel.Text = 'TauL min';
            app.TauLmaxLabel = uilabel(app.SelectTauLrangepsPanel);
            app.TauLmaxLabel.Text = 'TauL max';
            app.RelaxationRateOffsetSwitchLabel = uilabel(app.UIFigure);
            app.RelaxationRateOffsetSwitchLabel.Text = 'Relaxation Rate Offset';
            app.RelaxationRateOffsetSwitch = uiswitch(app.UIFigure, 'slider');
            app.RelaxationRateOffsetSwitch.ValueChangedFcn = createCallbackFcn(app, @RelaxationRateOffsetSwitchValueChanged, true);
            app.OffsetvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.InteractionsTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.InteractionsTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InteractionsTypeButtonGroupSelectionChanged, true);
            app.InteractionsTypeButtonGroup.Title = 'Interactions Type';
            app.HHButton = uiradiobutton(app.InteractionsTypeButtonGroup);
            app.HHButton.Text = 'HH';
            app.HHButton.Value = true;
            app.HMButton = uiradiobutton(app.InteractionsTypeButtonGroup);
            app.HMButton.Text = 'HM';
            app.HHHMButton = uiradiobutton(app.InteractionsTypeButtonGroup);
            app.HHHMButton.Text = 'HH+HM';
            app.ParamagDensityNparaPanel = uipanel(app.UIFigure);
            app.ParamagDensityNparaPanel.Title = 'Paramag. Density (Npara)';
            app.NovaluestestedEditFieldLabel = uilabel(app.ParamagDensityNparaPanel);
            app.NovaluestestedEditFieldLabel.Text = 'No. values tested ';
            app.NovaluestestedEditField = uieditfield(app.ParamagDensityNparaPanel, 'numeric');
            app.StartingValueEditFieldLabel = uilabel(app.ParamagDensityNparaPanel);
            app.StartingValueEditFieldLabel.Text = 'Starting Value';
            app.StartingValueEditField = uieditfield(app.ParamagDensityNparaPanel, 'numeric');
            app.IncrementationEditFieldLabel = uilabel(app.ParamagDensityNparaPanel);
            app.IncrementationEditFieldLabel.Text = 'Incrementation';
            app.IncrementationEditField = uieditfield(app.ParamagDensityNparaPanel, 'numeric');
            app.SurfaceVolumeRatioPanel = uipanel(app.UIFigure);
            app.SurfaceVolumeRatioPanel.Title = 'Surface Volume Ratio';
            app.NovaluestestedEditField_2Label = uilabel(app.SurfaceVolumeRatioPanel);
            app.NovaluestestedEditField_2Label.Text = 'No. values tested ';
            app.NovaluestestedEditField_2 = uieditfield(app.SurfaceVolumeRatioPanel, 'numeric');
            app.StartingValueEditField_2Label = uilabel(app.SurfaceVolumeRatioPanel);
            app.StartingValueEditField_2Label.Text = 'Starting Value';
            app.StartingValueEditField_2 = uieditfield(app.SurfaceVolumeRatioPanel, 'numeric');
            app.IncrementationEditField_2Label = uilabel(app.SurfaceVolumeRatioPanel);
            app.IncrementationEditField_2Label.Text = 'Incrementation';
            app.IncrementationEditField_2 = uieditfield(app.SurfaceVolumeRatioPanel, 'numeric');
            app.IntraMolecularContributionSwitchLabel = uilabel(app.UIFigure);
            app.IntraMolecularContributionSwitchLabel.Text = 'Intra Molecular Contribution ';
            app.IntraMolecularContributionSwitch = uiswitch(app.UIFigure, 'slider');
            app.IntraMolecularContributionSwitch.ValueChangedFcn = createCallbackFcn(app, @IntraMolecularContributionSwitchValueChanged, true);
            app.IntraMolContribution = uieditfield(app.UIFigure, 'numeric');
            app.LeastSquareMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.LeastSquareMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeastSquareMethodButtonGroupSelectionChanged, true);
            app.LeastSquareMethodButtonGroup.Title = 'Least Square Method';
            app.LinearButton = uiradiobutton(app.LeastSquareMethodButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogarithmicButton = uiradiobutton(app.LeastSquareMethodButtonGroup);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.SefFilesOnlyButton = uiradiobutton(app.LeastSquareMethodButtonGroup);
            app.SefFilesOnlyButton.Text = 'Sef Files Only';
            app.DiscardXpFrequenciesPanel = uipanel(app.UIFigure);
            app.DiscardXpFrequenciesPanel.Title = 'Discard Xp Frequencies';
            app.FreqStartEditFieldLabel = uilabel(app.DiscardXpFrequenciesPanel);
            app.FreqStartEditFieldLabel.Text = 'Freq Start';
            app.FreqStartEditField = uieditfield(app.DiscardXpFrequenciesPanel, 'numeric');
            app.FreqEndEditFieldLabel = uilabel(app.DiscardXpFrequenciesPanel);
            app.FreqEndEditFieldLabel.Text = 'Freq End';
            app.FreqEndEditField = uieditfield(app.DiscardXpFrequenciesPanel, 'numeric');
            app.TauBminLabel = uilabel(app.UIFigure);
            app.TauBminLabel.Text = 'TauB min';
            app.TauBmaxLabel = uilabel(app.UIFigure);
            app.TauBmaxLabel.Text = 'TauB max';
            app.ImposedValuesPanel = uipanel(app.UIFigure);
            app.ImposedValuesPanel.Title = 'Imposed Values';
            app.TauBpsEditFieldLabel = uilabel(app.ImposedValuesPanel);
            app.TauBpsEditFieldLabel.Text = 'TauB (ps)';
            app.TauBpsEditField = uieditfield(app.ImposedValuesPanel, 'numeric');
            app.TauBpsEditField.ValueChangedFcn = createCallbackFcn(app, @TauBpsEditFieldValueChanged, true);
            app.TauLpsEditFieldLabel = uilabel(app.ImposedValuesPanel);
            app.TauLpsEditFieldLabel.Text = 'TauL (ps)';
            app.TauLpsEditField = uieditfield(app.ImposedValuesPanel, 'numeric');
            app.TauLpsEditField.ValueChangedFcn = createCallbackFcn(app, @TauLpsEditFieldValueChanged, true);
            app.TauDpsEditFieldLabel = uilabel(app.ImposedValuesPanel);
            app.TauDpsEditFieldLabel.Text = 'TauD (ps)';
            app.TauDpsEditField = uieditfield(app.ImposedValuesPanel, 'numeric');
            app.TauDpsEditField.ValueChangedFcn = createCallbackFcn(app, @TauDpsEditFieldValueChanged, true);
            app.ParaDensityspinsnm3EditFieldLabel = uilabel(app.ImposedValuesPanel);
            app.ParaDensityspinsnm3EditFieldLabel.Text = 'Para Density (spins/nm3)';
            app.ParaDensityspinsnm3EditField = uieditfield(app.ImposedValuesPanel, 'numeric');
            app.ParaDensityspinsnm3EditField.ValueChangedFcn = createCallbackFcn(app, @ParaDensityspinsnm3EditFieldValueChanged, true);
            app.SurfaceVolumeRatioxEditFieldLabel = uilabel(app.ImposedValuesPanel);
            app.SurfaceVolumeRatioxEditFieldLabel.Text = 'Surface/Volume Ratio (x)';
            app.SurfaceVolumeRatioxEditField = uieditfield(app.ImposedValuesPanel, 'numeric');
            app.SurfaceVolumeRatioxEditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceVolumeRatioxEditFieldValueChanged, true);
            app.SurfDensityspinsnm3EditFieldLabel = uilabel(app.ImposedValuesPanel);
            app.SurfDensityspinsnm3EditFieldLabel.Text = 'Surf Density (spins/nm3)';
            app.SurfDensityspinsnm3EditField = uieditfield(app.ImposedValuesPanel, 'numeric');
            app.PrintResultsPanel = uipanel(app.UIFigure);
            app.PrintResultsPanel.Title = 'Print Results';
            app.TauBLabel = uilabel(app.PrintResultsPanel);
            app.TauBLabel.Text = 'TauB:';
            app.TauDLabel = uilabel(app.PrintResultsPanel);
            app.TauDLabel.Text = 'TauD:';
            app.TauLLabel = uilabel(app.PrintResultsPanel);
            app.TauLLabel.Text = 'TauL:';
            app.hLabel = uilabel(app.PrintResultsPanel);
            app.hLabel.Text = 'h:';
            app.xLabel = uilabel(app.PrintResultsPanel);
            app.xLabel.Text = 'x';
            app.NparaLabel = uilabel(app.PrintResultsPanel);
            app.NparaLabel.Text = 'Npara:';
            app.T1T2Label = uilabel(app.PrintResultsPanel);
            app.T1T2Label.Text = 'T1/T2:';
            app.Chi2minLabel = uilabel(app.PrintResultsPanel);
            app.Chi2minLabel.Text = 'Chi2 min:';
            app.TauDTauLLabel = uilabel(app.PrintResultsPanel);
            app.TauDTauLLabel.Text = 'TauD/TauL:';
            app.Message = uilabel(app.UIFigure);
            app.Message.Text = '';
            app.SaveOutputsafterFittingLabel = uilabel(app.UIFigure);
            app.SaveOutputsafterFittingLabel.Text = {'Save Outputs '; 'after Fitting'};
            app.SaveOutputsafterFittingSwitch = uiswitch(app.UIFigure, 'slider');
            app.SaveOutputsafterFittingSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveOutputsafterFittingSwitchValueChanged, true);
            app.LogLogScaleSwitchLabel = uilabel(app.UIFigure);
            app.LogLogScaleSwitchLabel.Text = 'LogLog Scale';
            app.LogLogScaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.LogLogScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @LogLogScaleSwitchValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = 0;
            app.UIAxes2.YTickLabel = {'0'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end